classdef Transformer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveButton                   matlab.ui.control.Button
        AdaptiveThresholdingButton   matlab.ui.control.Button
        ClosingOperationButton       matlab.ui.control.Button
        RGBtoHSVConversionButton     matlab.ui.control.Button
        SharpeningButton             matlab.ui.control.Button
        LogarithmicTransformationButton  matlab.ui.control.Button
        CannyEdgeDetectionButton     matlab.ui.control.Button
        BinaryThresholdingButton     matlab.ui.control.Button
        GuidedFilterButton           matlab.ui.control.Button
        LaplacianFilterButton        matlab.ui.control.Button
        DilationButton               matlab.ui.control.Button
        ErosionButton                matlab.ui.control.Button
        MedianFilterButton           matlab.ui.control.Button
        HistogramEqualizationButton  matlab.ui.control.Button
        ResizeButton                 matlab.ui.control.Button
        InvertColorsButton           matlab.ui.control.Button
        SobelEdgeDetectionButton     matlab.ui.control.Button
        GaussianBlurButton           matlab.ui.control.Button
        ContrastAdjustmentButton     matlab.ui.control.Button
        RotationButton               matlab.ui.control.Button
        GrayscaleButton              matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        ImageData                    matlab.ui.control.UIAxes
        ImageViewer                  matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % Transformation functions
        

function output_image = applyGuidedFilter(~, input_image)
    % Guided filter transformation
    
    % Ensure the input image is in double format
    input_image = im2double(input_image);
    
    % Apply guided filter
    guided_image = imguidedfilter(input_image);
    output_image = im2uint8(guided_image); % Convert back to uint8 if needed
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

            [file1,path1]=uigetfile('*.jpg;*.png;*.bmp;*.jpeg','Load the image');
            Image=imread([path1,file1]);
            imshow(Image,'Parent',app.ImageViewer);
            imshow(Image,'Parent',app.ImageData);
        end

        % Callback function
        function TranformationDropDownValueChanged(app, event)

        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            iimage = app.ImageData.Children.CData;
            if ndims(iimage) == 3
                iimage = rgb2gray(iimage);
            end
            imshow(iimage, 'Parent', app.ImageData);
        end

        % Button pushed function: GaussianBlurButton
        function GaussianBlurButtonPushed(app, event)
            h = fspecial('gaussian', [5 5], 2);
            timage = imfilter(app.ImageData ...
                .Children.CData, h);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            imshow(app.ImageViewer.Children.CData,'Parent',app.ImageData);
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            se = strel('disk', 5);
            timage = imerode(app.ImageData ...
                .Children.CData, se);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: ContrastAdjustmentButton
        function ContrastAdjustmentButtonPushed(app, event)
            contrast_factor = 1.5; % adjust this value as needed
            timage = imadjust(app.ImageData.Children.CData, [], [], contrast_factor);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: SobelEdgeDetectionButton
        function SobelEdgeDetectionButtonPushed(app, event)
            iimage = app.ImageData.Children.CData;
    
            % Check if the input image is RGB and convert to grayscale if needed
            if ismatrix(iimage)
                % Already grayscale
                input_image_gray = iimage;
            elseif ndims(iimage) == 3 && size(iimage, 3) == 3
                % RGB image, convert to grayscale
                input_image_gray = rgb2gray(iimage);
            else
                % Unsupported format, return
                disp('Unsupported image format');
                return;
            end
            timage = edge(input_image_gray ...
                , 'sobel');
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: InvertColorsButton
        function InvertColorsButtonPushed(app, event)
            timage = imcomplement(app.ImageData.Children.CData);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            iimage = im2double(app.ImageData.Children.CData);
            timage = histeq(iimage);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            iimage = app.ImageData.Children.CData;
            if ndims(iimage) == 3
                iimage = rgb2gray(iimage);
            end
            timage = medfilt2(iimage, [3, 3]);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
            se = strel('disk', 5);
            timage = imdilate(app.ImageData.Children.CData, se);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: LaplacianFilterButton
        function LaplacianFilterButtonPushed(app, event)
            timage = imfilter(app.ImageData.Children.CData, fspecial('laplacian'));
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: GuidedFilterButton
        function GuidedFilterButtonPushed(app, event)
            input_image = app.ImageData.Children.CData;
    
            % Apply guided filter
            timage = applyGuidedFilter(app, input_image);
            
            % Display the result
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: BinaryThresholdingButton
        function BinaryThresholdingButtonPushed(app, event)
            input_image = app.ImageData.Children.CData;
            % Apply binary thresholding
            threshold_value = 0.5; % adjust as needed
            timage = imbinarize(input_image, threshold_value);
            
            % Display the result using imagesc instead of imshow
            imagesc(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: CannyEdgeDetectionButton
        function CannyEdgeDetectionButtonPushed(app, event)
            input_image = app.ImageData.Children.CData;
        
            % Convert the input image to grayscale if it's not already
            if size(input_image, 3) == 3
                input_image = rgb2gray(input_image);
            end
            
            % Apply Canny edge detection
            timage = edge(input_image, 'canny');
            
            % Display the result using imagesc instead of imshow
            imagesc(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: LogarithmicTransformationButton
        function LogarithmicTransformationButtonPushed(app, event)
            timage = imadjust(app.ImageData.Children.CData, [], [], 0.1);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: SharpeningButton
        function SharpeningButtonPushed(app, event)
            timage = imsharpen(app.ImageData.Children.CData);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: RGBtoHSVConversionButton
        function RGBtoHSVConversionButtonPushed(app, event)
            timage = rgb2hsv(app.ImageData.Children.CData);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: ClosingOperationButton
        function ClosingOperationButtonPushed(app, event)
            se = strel('disk', 5);
            timage = imclose(app.ImageData.Children.CData, se);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: AdaptiveThresholdingButton
        function AdaptiveThresholdingButtonPushed(app, event)
            timage = adaptthresh(app.ImageData.Children.CData, 'NeighborhoodSize', 15);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            angle = 30; % specify the rotation angle
            timage = imrotate(app.ImageData.Children.CData, angle);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            new_size = [300, 300]; % specify the new size
            timage = imresize(app.ImageData.Children.CData, new_size);
            imshow(timage, 'Parent', app.ImageData);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            edited_image = app.ImageData.Children.CData;
            [file2, path2] = uiputfile({'*.png', 'PNG Files'; '*.jpg', 'JPEG Files'}, 'Save Edited Image As');
            if isequal(file2, 0) || isequal(path2, 0)
                return;
            end
            full_path = fullfile(path2, file2);
            imwrite(edited_image, full_path);
            msgbox('Edited image saved successfully!', 'Success', 'modal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageViewer = uiaxes(app.UIFigure);
            title(app.ImageViewer, 'Original')
            app.ImageData = uiaxes(app.UIFigure);
            title(app.ImageData, 'Edited')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.RotationButton = uibutton(app.UIFigure, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.ContrastAdjustmentButton = uibutton(app.UIFigure, 'push');
            app.ContrastAdjustmentButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastAdjustmentButtonPushed, true);
            app.ContrastAdjustmentButton.Text = 'Contrast Adjustment';
            app.GaussianBlurButton = uibutton(app.UIFigure, 'push');
            app.GaussianBlurButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianBlurButtonPushed, true);
            app.GaussianBlurButton.Text = 'Gaussian Blur';
            app.SobelEdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.SobelEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @SobelEdgeDetectionButtonPushed, true);
            app.SobelEdgeDetectionButton.Text = 'Sobel Edge Detection';
            app.InvertColorsButton = uibutton(app.UIFigure, 'push');
            app.InvertColorsButton.ButtonPushedFcn = createCallbackFcn(app, @InvertColorsButtonPushed, true);
            app.InvertColorsButton.Text = 'Invert Colors';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.HistogramEqualizationButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.ErosionButton = uibutton(app.UIFigure, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.DilationButton = uibutton(app.UIFigure, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.LaplacianFilterButton = uibutton(app.UIFigure, 'push');
            app.LaplacianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianFilterButtonPushed, true);
            app.LaplacianFilterButton.Text = 'Laplacian Filter';
            app.GuidedFilterButton = uibutton(app.UIFigure, 'push');
            app.GuidedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GuidedFilterButtonPushed, true);
            app.GuidedFilterButton.Text = 'Guided Filter';
            app.BinaryThresholdingButton = uibutton(app.UIFigure, 'push');
            app.BinaryThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryThresholdingButtonPushed, true);
            app.BinaryThresholdingButton.Text = 'Binary Thresholding';
            app.CannyEdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.CannyEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CannyEdgeDetectionButtonPushed, true);
            app.CannyEdgeDetectionButton.Text = 'Canny Edge Detection';
            app.LogarithmicTransformationButton = uibutton(app.UIFigure, 'push');
            app.LogarithmicTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @LogarithmicTransformationButtonPushed, true);
            app.LogarithmicTransformationButton.Text = 'Logarithmic Transformation';
            app.SharpeningButton = uibutton(app.UIFigure, 'push');
            app.SharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @SharpeningButtonPushed, true);
            app.SharpeningButton.Text = 'Sharpening';
            app.RGBtoHSVConversionButton = uibutton(app.UIFigure, 'push');
            app.RGBtoHSVConversionButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoHSVConversionButtonPushed, true);
            app.RGBtoHSVConversionButton.Text = 'RGB to HSV Conversion';
            app.ClosingOperationButton = uibutton(app.UIFigure, 'push');
            app.ClosingOperationButton.ButtonPushedFcn = createCallbackFcn(app, @ClosingOperationButtonPushed, true);
            app.ClosingOperationButton.Text = 'Closing Operation';
            app.AdaptiveThresholdingButton = uibutton(app.UIFigure, 'push');
            app.AdaptiveThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveThresholdingButtonPushed, true);
            app.AdaptiveThresholdingButton.Text = 'Adaptive Thresholding';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end